classdef voice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure matlab.ui.Figure         % UI Figure
        Button   matlab.ui.control.Button % Listen
        UIAxes   matlab.ui.control.UIAxes % Listened Voice
        UIAxes2  matlab.ui.control.UIAxes % Matched Voice
        Label    matlab.ui.control.Label  % Comparison Betwwen Listened voi...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            recObj = audiorecorder;             %creating audiorecorder object
            recordblocking(recObj,2);           %to record voice of user
            voicedata = getaudiodata(recObj);
            fs = 8000;
            filename = 'hello.wav';
            audiowrite(filename,voicedata,fs);   %It writes recorded voice into
            voice=audioread('hello.wav');        %hello.wav file
            x=voice;                          
            x=x';
            x=x(1,:);
            x=x';
            y1=audioread('hello.wav');        %Loading files which are in 
            y1=y1';                           %current directory to compare with     
            y1=y1(1,:);                       %Listened voice 
            y1=y1';
            z1=xcorr(x,y1);                   %Using correlation to compare 
            m1=max(z1);                       %Signal generated of both voices
            l1=length(z1);
            t1=-((l1-1)/2):1:((l1-1)/2);
            t1=t1';
            plot(app.UIAxes,t1,z1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            y2=audioread('hello5.wav');       %Similarly all five voices are
            y2=y2';                           %uploaded
            y2=y2(1,:);
            y2=y2';
            z2=xcorr(x,y2);
            m2=max(z2);
            l2=length(z2);
            t2=-((l2-1)/2):1:((l2-1)/2);
            t2=t2';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            y3=audioread('hello6.wav');        %Similarly for third voice
            y3=y3';
            y3=y3(1,:);
            y3=y3';
            z3=xcorr(x,y3);
            m3=max(z3);
            l3=length(z3);
            t3=-((l3-1)/2):1:((l3-1)/2);
            t3=t3';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            y4=audioread('hello7.wav');       %Similarly for fourth voice
            y4=y4';
            y4=y4(1,:);
            y4=y4';
            z4=xcorr(x,y4);
            m4=max(z4);
            l4=length(z4);
            t4=-((l4-1)/2):1:((l4-1)/2);
            t4=t4';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            y5=audioread('hello.wav');        %Similarly for fifth voice
            y5=y5';
            y5=y5(1,:);
            y5=y5';
            z5=xcorr(x,y5);
            m5=max(z5);
            l5=length(z5);
            t5=-((l5-1)/2):1:((l5-1)/2);
            t5=t5';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            m6=300;
            a=[m1 m2 m3 m4 m5 m6];
            m=max(a);                       %comparing the files
            if m<=m1                        %checking for best match
                plot(app.UIAxes2,t1,z1);    %displaying graph on software
            elseif m<=m2
                plot(app.UIAxes2,t2,z2);
            elseif m<=m3
                plot(app.UIAxes2,t3,z3);
            elseif m<=m4
                plot(app.UIAxes2,t4,z4);
            elseif m<m5
                plot(app.UIAxes2,t5,z5);
            else
                soundsc(audioread('denied.wav'),50000)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Listen';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Listened Voice');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Matched Voice');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Comparison Betwwen Listened voice and recorded voice';
        end
    end

    methods (Access = public)

        % Construct app
        function app = voice()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end